classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Function  matlab.ui.control.EditField
        Label_2   matlab.ui.control.Label
        Surf      matlab.ui.control.EditField
        Button_2  matlab.ui.control.Button
        Label_3   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            syms x y
            f = app.Function.Value;
            ezplot(f)
            hold on;
            k = str2sym(app.Function.Value);
            cs = coeffs(k);
            disp(cs);
            A = [cs(6) cs(5)/2 ; cs(5)/2 cs(3)];
            [V, D] = eig(A);
            A_1 = [cs(4)/2 cs(2)/2];
            B = A_1 * V;
            if (D(1,1) ~= 0 && D(2,2) ~= 0)
            x_0 = B(1)/D(1,1);
            y_0 = B(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(X_Y_0(1,1), X_Y_0(2,1), '*r')
            line([X_Y_0(1) X_Y_0(1) + V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,1)])
            line([X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            end
            if (D(1,1) == 0 && A_1(1,1) ~= 0)
            x_0 = (1./2.*B(1)).*(cs(1) - (B(2).^2)./D(2,2));
            y_0 = B(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(X_Y_0(1,1), X_Y_0(2,1), '*r')
            line([X_Y_0(1) X_Y_0(1) + V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,1)])
            line([X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            end
            if (D(1,1) == 0 && A_1(1,1) == 0)
            x_0 =0;
            y_0 = B(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(X_Y_0(1,1), X_Y_0(2,1), '*r')
            line([X_Y_0(1) X_Y_0(1) + V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,1)])
            line([X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            g = @(x, y, z) app.Surf.Value;
            fimplicit3(g)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить кривую';
            app.Function = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '= 0';
            app.Surf = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Построить поверхность';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '= 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end