classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        x2        matlab.ui.control.NumericEditField
        xy        matlab.ui.control.NumericEditField
        y2        matlab.ui.control.NumericEditField
        x         matlab.ui.control.NumericEditField
        y         matlab.ui.control.NumericEditField
        c         matlab.ui.control.NumericEditField
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.StateButton
        x2Label   matlab.ui.control.Label
        xyLabel   matlab.ui.control.Label
        y2Label   matlab.ui.control.Label
        xLabel    matlab.ui.control.Label
        yLabel    matlab.ui.control.Label
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            hold(app.UIAxes, 'on');
            A = app.x2.Value;
            B = app.xy.Value;
            C = app.y2.Value;
            D = app.x.Value;
            E = app.y.Value;
            F = app.c.Value;
            f = @(x, y) A.*x.^2 + B.*x.*y + C.*y.^2 + D.*x + E.*y + F;
            fimplicit(app.UIAxes,f)
            Mat = [A B/2 ; B/2 C];
            Mat_1 = [D/2  E/2];
            [V, D] = eig(Mat);
            Bat = Mat_1*V;
            if (D(1,1) ~= 0 && D(2,2) ~= 0)
            x_0 = -Bat(1)/D(1,1);
            y_0 = -Bat(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(app.UIAxes,X_Y_0(1), X_Y_0(2), '*r')
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) - V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) - V(2,1)])
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            title(app.UIAxes, "Эллипс")
            end
            if (D(1,1) == 0 && Mat_1(1,1) ~= 0)
            x_0 = -1/(2.*Bat(1)).*(F - (Bat(2).^2)./D(2,2));
            y_0 = -Bat(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(app.UIAxes,X_Y_0(1), X_Y_0(2), '*r')
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) - V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) - V(2,1)])
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            title(app.UIAxes, "Парабола")
            end
            if (D(1,1) == 0 && Mat_1(1,1) == 0)
            x_0 =0;
            y_0 = Bat(2)/D(2,2);
            X_Y = [x_0; y_0];
            X_Y_0 = V*X_Y;
            plot(app.UIAxes,X_Y_0(1), X_Y_0(2), '*r')
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) - V(1,1)], [X_Y_0(2,1) X_Y_0(2,1) - V(2,1)])
            line(app.UIAxes,[X_Y_0(1) X_Y_0(1) + V(1,2)], [X_Y_0(2,1) X_Y_0(2,1) + V(2,2)])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.x2 = uieditfield(app.UIFigure, 'numeric');
            app.xy = uieditfield(app.UIFigure, 'numeric');
            app.y2 = uieditfield(app.UIFigure, 'numeric');
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.y = uieditfield(app.UIFigure, 'numeric');
            app.c = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '= 0';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'построить кривую';
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x^2 +';
            app.xyLabel = uilabel(app.UIFigure);
            app.xyLabel.Text = 'x*y +';
            app.y2Label = uilabel(app.UIFigure);
            app.y2Label.Text = 'y^2 +';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x +';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y +';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'кривая')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end