classdef app_sin_cos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        Button      matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x = -10:0.1:10;
            if (app.CheckBox.Value)
                plot(app.UIAxes, sin(x));
            end
            if (app.CheckBox_2.Value)
                plot(app.UIAxes, cos(x));
            end
            if (app.CheckBox.Value && app.CheckBox_2.Value)
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, sin(x));
                plot(app.UIAxes, sin(x));
                hold(app.UIAxes, 'off');
            end
            if (not (app.CheckBox.Value | app.CheckBox_2.Value))
                cla(app.UIAxes);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'график синуса';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = 'график косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_sin_cos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end