classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label         matlab.ui.control.Label
        Label_3       matlab.ui.control.Label
        Label_4       matlab.ui.control.Label
        Label_5       matlab.ui.control.Label
        Label_6       matlab.ui.control.Label
        EditField     matlab.ui.control.EditField
        EditField_2   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        EditField_4   matlab.ui.control.EditField
        EditField2    matlab.ui.control.EditField
        EditField3    matlab.ui.control.EditField
        EditField_5   matlab.ui.control.NumericEditField
        EditField2_2  matlab.ui.control.NumericEditField
        EditField3_2  matlab.ui.control.NumericEditField
        EditField_6   matlab.ui.control.NumericEditField
        EditField_7   matlab.ui.control.EditField
        Button        matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if(app.EditField3_2.Value > 100 || app.EditField3_2.Value < 0)
                errordlg('Баллы от 0 до 100!');
            end
           if(app.EditField2_2.Value > 100 && app.EditField2_2.Value < 0)
                errordlg('Баллы от 0 до 100!');
                        end
            if(app.EditField_5.Value > 100 && app.EditField_5.Value < 0)
                errordlg('Баллы от 0 до 100!');
            end
            a_1 = app.EditField3_2.Value;
            a_2 = app.EditField2_2.Value;
            a_3 = app.EditField_5.Value;
            A = a_3 + a_2 + a_1;
            
            app.EditField_6.Value = A;
            
            if(A> 255)
                app.EditField_7.Value = 'Поздравляем!';
            else
                app.EditField_7.Value = 'Сожалеем :(';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, Имя, Отчество';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Сумма баллов :';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Предмет';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Баллы ЕГЭ';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Проходной балл: 250';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Вычисление';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end